classdef MentalHealthApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HealthyBrainAppLabel            matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        HistoryButton                   matlab.ui.control.Button
        SaveMoodButton                  matlab.ui.control.Button
        NotesTextArea                   matlab.ui.control.TextArea
        NotesTextAreaLabel              matlab.ui.control.Label
        SelectMoodDropDown              matlab.ui.control.DropDown
        SelectMoodDropDownLabel         matlab.ui.control.Label
        MoodTrackingLabel               matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GuidedMeditationLabel           matlab.ui.control.Label
        VolumeLabel                     matlab.ui.control.Label
        VolumeSlider                    matlab.ui.control.Slider
        MeditationDropdownDropDown      matlab.ui.control.DropDown
        MeditationDropdownDropDownLabel  matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        PeerSupportChatRoomsButton      matlab.ui.control.Button
        RealTimeTriggerRemindersButton  matlab.ui.control.Button
        MentalHealthCounselingProgressReportsButton  matlab.ui.control.Button
        ResourceLibraryButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        Volume = 1; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
           % Get the selected meditation file
    selectedFile = fullfile(app.MeditationDropdownDropDown.Value);
    disp(['Selected file: ', selectedFile]);
    
    try
        % Read the selected audio file
        [y, Fs] = audioread(selectedFile);
        
        % Scale the audio data based on the volume slider value
        y_scaled = y * app.Volume;  % Multiply audio data by volume factor
        
        % Play the scaled audio with the selected volume
        sound(y_scaled, Fs);  % Play the scaled sound
    catch ME
        % Handle errors gracefully
        uialert(app.UIFigure, 'Unable to play the audio. Check the file path or format.', 'Audio Error');
        disp(['Error: ', ME.message]);
    end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            value = app.VolumeSlider.Value;
            disp(value);
            app.Volume = value;
        end

        % Button pushed function: SaveMoodButton
        function SaveMoodButtonPushed(app, event)
            % Get the selected mood and notes from the UI components
    currentMood = app.SelectMoodDropDown.Value;    % Selected mood from dropdown
    notes = app.NotesTextArea.Value;          % Notes from text area
    
    % Get the current date and time to record when the mood was logged
    currentDate = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
    
    % Prepare a structure to hold the mood entry
    moodEntry = struct('date', currentDate, 'mood', currentMood, 'notes', notes);
    
    % Check if the JSON file exists (this will hold the mood history)
    if exist('moodHistory.json', 'file') == 2
        % If the file exists, read the existing data
        moodData = jsondecode(fileread('moodHistory.json'));
    else
        % If no file exists, initialize an empty array for the data
        moodData = struct([]);
    end
    
    % Add the new mood entry to the mood data array
    moodData = [moodData; moodEntry];
    
    % Save the updated mood data back to the JSON file
    fid = fopen('moodHistory.json', 'w');
    fwrite(fid, jsonencode(moodData));
    fclose(fid);
    
    % Display a confirmation message (optional)
    uialert(app.UIFigure, 'Mood saved successfully!', 'Success');
    
    % Optionally, clear the text area after saving (optional)
    app.NotesTextArea.Value = '';
        end

        % Button pushed function: HistoryButton
        function HistoryButtonPushed(app, event)
            app2();

        end

        % Button pushed function: ResourceLibraryButton
        function ResourceLibraryButtonPushed(app, event)
            app3();
        end

        % Button pushed function: 
        % MentalHealthCounselingProgressReportsButton
        function MentalHealthCounselingProgressReportsButtonPushed(app, event)
            ReportApp();
        end

        % Button pushed function: RealTimeTriggerRemindersButton
        function RealTimeTriggerRemindersButtonPushed(app, event)
            TriggerReminders();
        end

        % Button pushed function: PeerSupportChatRoomsButton
        function PeerSupportChatRoomsButtonPushed(app, event)
             % Open the Telegram link in the default web browser
                web('https://chat.whatsapp.com/Luvx1yMn1t78XUdKlvs94F', '-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResourceLibraryButton = uibutton(app.UIFigure, 'push');
            app.ResourceLibraryButton.ButtonPushedFcn = createCallbackFcn(app, @ResourceLibraryButtonPushed, true);
            app.ResourceLibraryButton.Text = 'Resource Library';
            app.MentalHealthCounselingProgressReportsButton = uibutton(app.UIFigure, 'push');
            app.MentalHealthCounselingProgressReportsButton.ButtonPushedFcn = createCallbackFcn(app, @MentalHealthCounselingProgressReportsButtonPushed, true);
            app.MentalHealthCounselingProgressReportsButton.Text = 'Mental Health Counseling Progress Reports';
            app.RealTimeTriggerRemindersButton = uibutton(app.UIFigure, 'push');
            app.RealTimeTriggerRemindersButton.ButtonPushedFcn = createCallbackFcn(app, @RealTimeTriggerRemindersButtonPushed, true);
            app.RealTimeTriggerRemindersButton.Text = 'Real-Time Trigger Reminders';
            app.PeerSupportChatRoomsButton = uibutton(app.UIFigure, 'push');
            app.PeerSupportChatRoomsButton.ButtonPushedFcn = createCallbackFcn(app, @PeerSupportChatRoomsButtonPushed, true);
            app.PeerSupportChatRoomsButton.Text = 'Peer Support Chat Rooms';
            app.Panel = uipanel(app.UIFigure);
            app.PlayButton = uibutton(app.Panel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.Panel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.MeditationDropdownDropDownLabel = uilabel(app.Panel);
            app.MeditationDropdownDropDownLabel.Text = 'Meditation Dropdown';
            app.MeditationDropdownDropDown = uidropdown(app.Panel);
            app.MeditationDropdownDropDown.Items = {'Meditation.mp3', 'Focus.mp3', 'Calmness.mp3'};
            app.MeditationDropdownDropDown.Value = 'Meditation.mp3';
            app.VolumeSlider = uislider(app.Panel);
            app.VolumeSlider.Limits = [0 1];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.Value = 1;
            app.VolumeLabel = uilabel(app.Panel);
            app.VolumeLabel.Text = 'Volume';
            app.GuidedMeditationLabel = uilabel(app.Panel);
            app.GuidedMeditationLabel.Text = 'Guided Meditation';
            app.Panel_2 = uipanel(app.UIFigure);
            app.MoodTrackingLabel = uilabel(app.Panel_2);
            app.MoodTrackingLabel.Text = 'Mood Tracking';
            app.SelectMoodDropDownLabel = uilabel(app.Panel_2);
            app.SelectMoodDropDownLabel.Text = 'Select Mood';
            app.SelectMoodDropDown = uidropdown(app.Panel_2);
            app.SelectMoodDropDown.Items = {'Happy', 'Sad', 'Anxious', 'Calm', 'Angry', 'Stressed'};
            app.SelectMoodDropDown.Value = 'Happy';
            app.NotesTextAreaLabel = uilabel(app.Panel_2);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.Panel_2);
            app.SaveMoodButton = uibutton(app.Panel_2, 'push');
            app.SaveMoodButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMoodButtonPushed, true);
            app.SaveMoodButton.Text = 'SaveMood';
            app.HistoryButton = uibutton(app.Panel_2, 'push');
            app.HistoryButton.ButtonPushedFcn = createCallbackFcn(app, @HistoryButtonPushed, true);
            app.HistoryButton.Text = 'History';
            app.HealthyBrainAppLabel = uilabel(app.UIFigure);
            app.HealthyBrainAppLabel.Text = 'Healthy Brain App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MentalHealthApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end