classdef ReportApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        THESEAREBASEDONTHEINPUTSPROVIDEDINTHEMOODTRACKINGSECTIONLabel  matlab.ui.control.Label
        ReportsLabel    matlab.ui.control.Label
        LoadDataButton  matlab.ui.control.Button
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Load the JSON file
            data = jsondecode(fileread('moodHistory.json'));
            % Extract mood and date
            dates = {data.date};
            moods = {data.mood};
            % Convert dates to a datetime array
dates = datetime(dates, 'InputFormat', 'yyyy-MM-dd HH:mm:ss');

% Map moods to numerical values (example: Happy=1, Angry=2, etc.)
moodMap = containers.Map({'Happy', 'Angry', 'Anxious'}, {1, 2, 3});
moodValues = cellfun(@(x) moodMap(x), moods);

% Plot the mood over time
plot(app.UIAxes, dates, moodValues, '-o', 'MarkerFaceColor', 'b');
xlabel(app.UIAxes, 'Date');
ylabel(app.UIAxes, 'Mood');
title(app.UIAxes, 'Mood Over Time');
% Calculate the number of sessions per day
[uniqueDates, ~, idx] = unique(dates);
sessionsPerDay = accumarray(idx(:), 1, [], @sum);

% Plot the session progress
bar(app.UIAxes2, uniqueDates, sessionsPerDay);
xlabel(app.UIAxes2, 'Date');
ylabel(app.UIAxes2, 'Number of Sessions');
title(app.UIAxes2, 'Session Progress');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mood Over Time')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Session Progress')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tag = 'LoadDataButton';
            app.LoadDataButton.Text = 'Load Data';
            app.ReportsLabel = uilabel(app.UIFigure);
            app.ReportsLabel.Text = 'Reports';
            app.THESEAREBASEDONTHEINPUTSPROVIDEDINTHEMOODTRACKINGSECTIONLabel = uilabel(app.UIFigure);
            app.THESEAREBASEDONTHEINPUTSPROVIDEDINTHEMOODTRACKINGSECTIONLabel.Text = 'THESE ARE BASED ON THE INPUTS PROVIDED IN THE MOOD TRACKING SECTION ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReportApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end