classdef TriggerReminders < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TriggerRemindersLabel    matlab.ui.control.Label
        SetReminderTimeLabel     matlab.ui.control.Label
        ReminderMessageTextArea  matlab.ui.control.TextArea
        Label                    matlab.ui.control.Label
        SaveReminderButton       matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        HourSpinner              matlab.ui.control.Spinner
        HourSpinnerLabel         matlab.ui.control.Label
        MinuteSpinner            matlab.ui.control.Spinner
        MinuteSpinnerLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
    ReminderSettings         struct  % Store reminder settings here
    ReminderTimer            timer  % Timer to check reminders
end

    
    methods (Access = private)
        
        function checkReminder(app)
    % Get the current time (with format HH:mm)
    currentTime = datetime('now', 'Format', 'HH:mm');
    
    % Extract the time part from ReminderTime (HH:mm)
    reminderTime = app.ReminderSettings.ReminderTime;
    reminderTimeFormatted = datetime(reminderTime, 'Format', 'HH:mm');
    
    % Compare the current time with the saved reminder time
    if strcmp(char(currentTime), char(reminderTimeFormatted))
        % If the time matches, show the reminder message
        uialert(app.UIFigure, app.ReminderSettings.Message, 'Reminder!');
    end
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveReminderButton
        function SaveReminderButtonPushed(app, event)
            % Get the reminder time from the spinners (hour and minute)
    reminderHour = app.HourSpinner.Value;
    reminderMinute = app.MinuteSpinner.Value;
    
    % Get the custom reminder message from the text area
    reminderMessage = app.ReminderMessageTextArea.Value;
    
    % Combine hour and minute into a datetime object for comparison later
    reminderTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
    reminderTime.Hour = reminderHour;
    reminderTime.Minute = reminderMinute;
    
    % Store the reminder settings (in app properties for later use)
    app.ReminderSettings = struct('ReminderTime', reminderTime, ...
                                  'Message', reminderMessage);
    
    % Show a confirmation message in the UI
    uialert(app.UIFigure, 'Reminder settings saved!', 'Success');
    
    % Start a timer to check for the reminder time
    app.ReminderTimer = timer( ...
        'ExecutionMode', 'fixedRate', ... % Trigger at regular intervals
        'Period', 60, ... % Check every minute
        'TimerFcn', @(src, event) checkReminder(app)); % Callback function
    start(app.ReminderTimer); % Start the timer
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.MinuteSpinnerLabel = uilabel(app.Panel);
            app.MinuteSpinnerLabel.Tag = 'MinuteSpinner';
            app.MinuteSpinnerLabel.Text = 'Minute';
            app.MinuteSpinner = uispinner(app.Panel);
            app.MinuteSpinner.Limits = [0 59];
            app.HourSpinnerLabel = uilabel(app.Panel);
            app.HourSpinnerLabel.Text = 'Hour';
            app.HourSpinner = uispinner(app.Panel);
            app.HourSpinner.Limits = [0 23];
            app.HourSpinner.Tag = 'HourSpinner';
            app.SaveReminderButton = uibutton(app.UIFigure, 'push');
            app.SaveReminderButton.ButtonPushedFcn = createCallbackFcn(app, @SaveReminderButtonPushed, true);
            app.SaveReminderButton.Text = 'Save Reminder';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ReminderMessageTextArea';
            app.ReminderMessageTextArea = uitextarea(app.UIFigure);
            app.ReminderMessageTextArea.Tag = 'ReminderMessageTextArea';
            app.ReminderMessageTextArea.Placeholder = '"Enter your reminder message here..."';
            app.SetReminderTimeLabel = uilabel(app.UIFigure);
            app.SetReminderTimeLabel.Text = 'Set Reminder Time';
            app.TriggerRemindersLabel = uilabel(app.UIFigure);
            app.TriggerRemindersLabel.Text = 'Trigger Reminders';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TriggerReminders

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end