classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GetDataButton  matlab.ui.control.Button
        UITable        matlab.ui.control.Table
    end

    
    methods (Access = private)
        
        function startupFcn(app)
    % Check if the JSON file exists
    if exist('moodHistory.json', 'file') == 2
        % If the file exists, read the data
        moodData = jsondecode(fileread('moodHistory.json'));
        disp(moodData);  % Debugging line to check if data is being read
        % Convert the data into a cell array for the table
        tableData = cell(length(moodData), 3);  % 3 columns: Date, Mood, Notes
        for i = 1:length(moodData)
            tableData{i, 1} = moodData(i).date;  % Date
            tableData{i, 2} = moodData(i).mood;  % Mood
            tableData{i, 3} = moodData(i).notes; % Notes
        end

        % Update the table with the data
        app.UITable.Data = tableData;
    else
        % If no file exists, show an empty table
        app.UITable.Data = {};
    end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)
            startupFcn(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Date'; 'Mood'; 'Notes'};
            app.UITable.RowName = {};
            app.GetDataButton = uibutton(app.UIFigure, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Text = 'Get Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end