classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ResourceDetailsPanel     matlab.ui.container.Panel
        ListBox                  matlab.ui.control.ListBox
        ListBoxLabel             matlab.ui.control.Label
        ResourceDetailsLabel     matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        Label                    matlab.ui.control.Label
        CategoriesPanel          matlab.ui.container.Panel
        TherapyTechniquesButton  matlab.ui.control.Button
        AnxietyReliefButton      matlab.ui.control.Button
        CopingStrategiesButton   matlab.ui.control.Button
        MentalHealthResourceLibraryLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CopingStrategiesButton
        function CopingStrategiesButtonPushed(app, event)
            % Update the List Box with resources for Coping Strategies
    app.ListBox.Items = { ...
        'Deep Breathing Techniques', ...
        'Journaling Prompts for Anxiety', ...
        'Grounding Exercises for Stress', ...
        'Guided Meditation Video' ...
    };
        end

        % Button pushed function: AnxietyReliefButton
        function AnxietyReliefButtonPushed(app, event)
            % Update the List Box with resources for Anxiety Relief
    app.ListBox.Items = { ...
        'Progressive Muscle Relaxation', ...
        'Self-Compassion Exercises', ...
        'Cognitive Restructuring Techniques' ...
    };
        end

        % Button pushed function: TherapyTechniquesButton
        function TherapyTechniquesButtonPushed(app, event)
            % Update the List Box with resources for Therapy Techniques
    app.ListBox.Items = { ...
        'Cognitive Behavioral Therapy Basics', ...
        'Exposure Therapy Guide', ...
        'Mindfulness-Based Stress Reduction' ...
    };
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            selectedResource = app.ListBox.Value;
            % Display details based on the selected resource
    switch selectedResource
        % Coping Strategies
        case 'Deep Breathing Techniques'
            app.TextArea.Value = "Deep breathing is a powerful relaxation tool. Practice by inhaling for 4 seconds, holding for 4 seconds, and exhaling for 4 seconds. This can lower your heart rate and reduce anxiety.";
        case 'Journaling Prompts for Anxiety'
            app.TextArea.Value = "Write down your thoughts to identify stress triggers. Example prompts: 'What am I feeling right now?' and 'What can I control today?'";
        case 'Grounding Exercises for Stress'
            app.TextArea.Value = "Grounding exercises bring you back to the present. Example: Identify 5 things you see, 4 things you can touch, 3 things you hear, 2 things you smell, and 1 thing you taste.";
        case 'Guided Meditation Video'
            app.TextArea.Value = "Meditation can calm your mind. Watch this video: https://example.com/meditation.";

        % Anxiety Relief
        case 'Progressive Muscle Relaxation'
            app.TextArea.Value = "Tense and relax each muscle group from toes to head. This reduces physical tension linked to anxiety.";
        case 'Self-Compassion Exercises'
            app.TextArea.Value = "Practice self-kindness during tough times. Example: Write a compassionate letter to yourself.";
        case 'Cognitive Restructuring Techniques'
            app.TextArea.Value = "Identify and challenge negative thoughts. Replace 'I can't handle this' with 'I am learning and growing.'";
        case 'Relaxation Music Playlist'
            app.TextArea.Value = "Listen to calming music. Visit: https://example.com/relaxation-music.";

        % Therapy Techniques
        case 'Cognitive Behavioral Therapy Basics'
            app.TextArea.Value = "CBT helps you recognize and change negative thought patterns. Start by keeping a thought diary.";
        case 'Exposure Therapy Guide'
            app.TextArea.Value = "Face your fears gradually with exposure therapy. Example: Start with imagining the fear, then take small steps toward confronting it.";
        case 'Mindfulness-Based Stress Reduction (MBSR)'
            app.TextArea.Value = "MBSR uses mindfulness and yoga to manage stress. Practice 10 minutes of focused breathing daily.";
        case 'Acceptance and Commitment Therapy (ACT)'
            app.TextArea.Value = "ACT encourages accepting your feelings while focusing on actions that align with your values.";

        % External Support Links
        case 'National Mental Health Helpline'
            app.TextArea.Value = "Call 1-800-273-TALK (USA) or check local helpline numbers for immediate mental health support.";
        case 'Online Therapy Platforms'
            app.TextArea.Value = "Access affordable therapy at https://www.betterhelp.com or https://www.talkspace.com.";
        case 'Support Group Finder'
            app.TextArea.Value = "Join local or virtual mental health support groups. Visit: https://example.com/support-groups.";
        case 'Mental Health Apps'
            app.TextArea.Value = "Try apps like Calm, Headspace, or Moodpath for guided exercises and tracking your mental health.";

        % Default Case
        otherwise
            app.TextArea.Value = "Select a resource to view details.";
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MentalHealthResourceLibraryLabel = uilabel(app.UIFigure);
            app.MentalHealthResourceLibraryLabel.Text = 'Mental Health Resource Library';
            app.CategoriesPanel = uipanel(app.UIFigure);
            app.CategoriesPanel.Title = 'Categories';
            app.CopingStrategiesButton = uibutton(app.CategoriesPanel, 'push');
            app.CopingStrategiesButton.ButtonPushedFcn = createCallbackFcn(app, @CopingStrategiesButtonPushed, true);
            app.CopingStrategiesButton.Text = 'Coping Strategies';
            app.AnxietyReliefButton = uibutton(app.CategoriesPanel, 'push');
            app.AnxietyReliefButton.ButtonPushedFcn = createCallbackFcn(app, @AnxietyReliefButtonPushed, true);
            app.AnxietyReliefButton.Text = 'Anxiety Relief';
            app.TherapyTechniquesButton = uibutton(app.CategoriesPanel, 'push');
            app.TherapyTechniquesButton.ButtonPushedFcn = createCallbackFcn(app, @TherapyTechniquesButtonPushed, true);
            app.TherapyTechniquesButton.Text = 'Therapy Techniques';
            app.ResourceDetailsPanel = uipanel(app.UIFigure);
            app.ResourceDetailsPanel.Title = 'Resource Details';
            app.Label = uilabel(app.ResourceDetailsPanel);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.ResourceDetailsPanel);
            app.TextArea.Value = {'Select a resource to view details.'};
            app.ResourceDetailsLabel = uilabel(app.ResourceDetailsPanel);
            app.ResourceDetailsLabel.Text = 'Resource Details:';
            app.ListBoxLabel = uilabel(app.ResourceDetailsPanel);
            app.ListBoxLabel.Text = 'List Box :';
            app.ListBox = uilistbox(app.ResourceDetailsPanel);
            app.ListBox.Items = {'Sub Categories'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Sub Categories';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end